classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        hEditField                  matlab.ui.control.NumericEditField
        hEditFieldLabel             matlab.ui.control.Label
        StartButton                 matlab.ui.control.StateButton
        DropDown                    matlab.ui.control.DropDown
        RandomInitializationButton  matlab.ui.control.Button
        MEditField                  matlab.ui.control.NumericEditField
        MEditFieldLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Parameters
        N = 5           % Number of robots
        M = 5           % Number of goals
        alg_type = 0    % Type of the using algorithm (0-C; 1-D)
        R = 0.5         % Robot radius
        ws = [20, 20]   % Workspace dimension
        vmax = 0.5      % maximum velocity

        % Display items
        X               % Robot current positions
        G               % Goal positions
        X0              % Robot initial positions

        % D-CAPT
        h = 2           % Robot sensing distance
        h_sense = []    % Sensing range handle

        % Handles
        h_r = []        % Robot handle
        h_r_t           % Robot text (label) handle
        h_path = []     % The path robots travelled
        h_plan = []     % The path robots planned
%         h_g            % Goal handle
%         h_g_t          % Goal text (label) handle
    end
    
    methods (Access = public)
        
        function drawItems(app)
            % Draw all robots and goals
            axis(app.UIAxes, "equal");
            app.h_r = []; app.h_r_t = [];
            if app.alg_type
                app.h_sense = [];
            end
            % Draw robot
%             app.h_r = scatter(app.UIAxes, app.X(:, 1), app.X(:, 2), ...
%                 app.R, "black", "filled", "o", "MarkerFaceAlpha",0.2, ...
%                 "MarkerEdgeAlpha", 0.2);
            for i=1:app.N
                pos = [app.X(i, 1)-app.R, app.X(i, 2)-app.R, ...
                    2*app.R, 2*app.R];
                app.h_r = [app.h_r; rectangle(app.UIAxes, ...
                    'Position',pos,'Curvature',[1 1], ...
                    'FaceColor', [1, 0, 0, 0.4], 'EdgeColor', "none")];
                if app.alg_type
                    pos = [app.X(i, 1)-app.h, app.X(i, 2)-app.h, ...
                        2*app.h, 2*app.h];
                    app.h_sense = [app.h_sense; rectangle(app.UIAxes, ...
                        'Position',pos,'Curvature',[1 1], ...
                        'FaceColor', [1, 0, 0, 0.1], 'EdgeColor', "none")];
                end
            end
            app.h_r_t = text(app.UIAxes, app.X(:, 1), app.X(:, 2), ...
                num2str([1:app.N]'), "HorizontalAlignment","center", ...
                "VerticalAlignment", "middle","FontSize", 12);

            % Draw goals
            for i=1:app.M
                pos = [app.G(i, 1)-app.R, app.G(i, 2)-app.R, ...
                    2*app.R, 2*app.R];
%                 app.h_g = [app.h_g; rectangle(app.UIAxes, ...
%                     'Position',pos,'Curvature',[1 1], ...
%                     'LineStyle','--')];
                rectangle(app.UIAxes, ...
                    'Position',pos,'Curvature',[1 1], ...
                    'LineStyle','--')
            end
            text(app.UIAxes, app.G(:, 1), app.G(:, 2), ...
                num2str([1:app.M]'), "HorizontalAlignment","center", ...
                "VerticalAlignment", "middle","FontSize", 12);
        end
        
        function updateRobots(app)
            app.UIAxes.XLim = [0, app.ws(1)];
            app.UIAxes.YLim = [0, app.ws(2)];
            for i=1:app.N
%                 pos_i = app.h_r(i).Position;
                app.h_r(i).Position = [app.X(i, 1)-app.R, app.X(i, 2)-app.R, ...
                    2*app.R, 2*app.R];
                app.h_r_t(i).Position = [app.X(i, 1), app.X(i, 2), 0];
                if app.alg_type
                    app.h_sense(i).Position = [app.X(i, 1)-app.h, ...
                        app.X(i, 2)-app.h, 2*app.h, 2*app.h];
                end
                app.h_path(i).XData = [app.h_path(i).XData, app.X(i, 1)];
                app.h_path(i).YData = [app.h_path(i).YData, app.X(i, 2)];
                app.h_plan(i).XData = [app.X(i, 1), app.G(i, 1)];
                app.h_plan(i).YData = [app.X(i, 2), app.G(i, 2)];
            end
        end
        
        function drawPath(app)
            hold(app.UIAxes, 'on');
            app.h_path = []; app.h_plan = [];
            for i=1:app.N
                app.h_path = [app.h_path, plot(app.UIAxes, ...
                    [app.X0(i,1), app.X(i,1)], [app.X0(i,2), app.X(i,2)], ...
                    "Color", 'k')];
                app.h_plan = [app.h_plan, plot(app.UIAxes, ...
                    [app.X(i,1), app.G(i,1)], [app.X(i,2), app.G(i,2)], ...
                    "LineStyle","--", "Color", 'k')];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.N = int32(value);
            app.NEditField.Value = double(app.N);  % Change to interger if not
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.M = int32(value);
            app.MEditField.Value = double(app.M);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "C-CAPT"
                app.alg_type = 0;
%                 disp(app.alg_type);
            elseif value == "D-CAPT"
                app.alg_type = 1;
%                 disp(app.alg_type);
            else
                error("Unknown value input.");
            end
        end

        % Button pushed function: RandomInitializationButton
        function RandomInitializationButtonPushed(app, event)
            % TODO: call user defined functions 
            % (initialize + run the algorithm)
            cla(app.UIAxes);
            [app.X, app.G] = RandomInit(app.N, app.M, app.R, app.ws);
            app.X0 = app.X;
            app.drawItems();
            app.UIAxes.XLim = [0, app.ws(1)];
            app.UIAxes.YLim = [0, app.ws(2)];
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value
                dt = 0.1;
                if ~app.alg_type
                    [gamma_x, gamma_y, app.G] = CCAPT(app.X, app.G, app.vmax, dt);
                    app.drawPath();
                    [T, ~] = size(gamma_x);
                    for t=1:T
                        app.X(:, 1) = gamma_x(t, :)';
                        app.X(:, 2) = gamma_y(t, :)';
                        app.updateRobots();
                        pause(dt);
                    end
                else
                    [app.X, app.G, G_R, U, is_stopped] = DCAPT_step(...
                        app.X, app.G, app.h, app.vmax, dt);
                    app.drawPath();
                    while ~is_stopped
                        [app.X, app.G, G_R, U, is_stopped] = DCAPT_step(...
                            app.X, app.G, app.h, app.vmax, dt, U, G_R);
                        app.updateRobots();
                        pause(dt);
                    end
                end
                disp("finished!");
                app.StartButton.Value = ~value;
            end
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            app.h = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {154, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NEditFieldLabel = uilabel(app.LeftPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 5;
            app.MEditFieldLabel = uilabel(app.LeftPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 5;
            app.RandomInitializationButton = uibutton(app.LeftPanel, 'push');
            app.RandomInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @RandomInitializationButtonPushed, true);
            app.RandomInitializationButton.Text = {'Random'; 'Initialization'};
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'C-CAPT', 'D-CAPT'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'C-CAPT';
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.hEditFieldLabel = uilabel(app.LeftPanel);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.LeftPanel, 'numeric');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Value = 2;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end